classdef m_h_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MMaxInfoUIFigure             matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        SubjectFolderEditFieldLabel  matlab.ui.control.Label
        SubjectFolderEditField       matlab.ui.control.EditField
        SelectButton                 matlab.ui.control.Button
        InvolvedSideButtonGroup      matlab.ui.container.ButtonGroup
        Left                         matlab.ui.control.RadioButton
        Right                        matlab.ui.control.RadioButton
        SaveButton                   matlab.ui.control.Button
        SubjectEditFieldLabel        matlab.ui.control.Label
        SubjectEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
%             ML_W_switch('ml');
            subj_dir = uigetdir(pwd, 'Select Subject Folder');
            if ischar(subj_dir)
                app.SubjectFolderEditField.Value = subj_dir;
                init_table(app, subj_dir)
                % parse subject from subj_dir
                subj = parse_fname_for_subj(subj_dir);
                app.SubjectEditField.Value = subj;
                % look up involved side from database
                side = find_subject_involved_side(subj);
                % check the correct involved side radio button
                switch side
                    case 'left'
                        app.Left.Value = 1;
                    case 'right'
                        app.Right.Value = 1;
                    otherwise
                        warning('unknown side in database for subject %s', subj)
                end
            end
%             ML_W_switch('mlw');
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            view_row = event.Indices(1);
            switch event.Indices(2)
                case 1
                    h_or_m = 'm';
                case 2
                    h_or_m = 'h';
            end
            view_val = event.NewData;
            if view_val % turn on viewing the data
                open_view_data_fig(app, view_row, h_or_m)
            else % turn off viewing the data
                close_view_data_fig(app, view_row, h_or_m)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            subj_dir = app.SubjectFolderEditField.Value;
                n_cols = width(app.UITable.Data);
                if n_cols > 14  % m & h data
                    tbl = app.UITable.Data(:,3:n_cols);
                    writetable(tbl, fullfile(subj_dir, 'm_max_h_reflex.csv'))
                else
                    tbl = app.UITable.Data(:,2:n_cols);
                    writetable(tbl, fullfile(subj_dir, 'm_max.csv'))
                end
                
            app.UITable.UserData.table_changed = false;
        end

        % Close request function: MMaxInfoUIFigure
        function MMaxInfoUIFigureCloseRequest(app, event)
           if isfield(app.UITable.UserData, 'table_changed')
            if app.UITable.UserData.table_changed
             but_name = questdlg('Save data changed in m_max table?', 'Save Table');
             switch but_name
              case 'Yes'
               SaveButtonPushed(app, event)
              case 'Cancel'
               return
             end
            end
           end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MMaxInfoUIFigure = uifigure('Visible', 'off');
            app.MMaxInfoUIFigure.Name = 'M-Max Info';
            app.MMaxInfoUIFigure.CloseRequestFcn = createCallbackFcn(app, @MMaxInfoUIFigureCloseRequest, true);
            app.UITable = uitable(app.MMaxInfoUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SubjectFolderEditFieldLabel = uilabel(app.MMaxInfoUIFigure);
            app.SubjectFolderEditFieldLabel.Text = 'Subject Folder';
            app.SubjectFolderEditField = uieditfield(app.MMaxInfoUIFigure, 'text');
            app.SubjectFolderEditField.Editable = 'off';
            app.SubjectFolderEditField.Tooltip = {'Path to subject''s m-max data folder shown here. Use Select button to change it.'};
            app.SelectButton = uibutton(app.MMaxInfoUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Select the subject folder with their m_max.csv file (not .xlsx) and subfolders with the exported data from Cadwell for each session.'};
            app.SelectButton.Text = 'Select';
            app.InvolvedSideButtonGroup = uibuttongroup(app.MMaxInfoUIFigure);
            app.InvolvedSideButtonGroup.Tooltip = {'Subject''s involved side will be looked up in the database and shown here.'};
            app.InvolvedSideButtonGroup.Title = 'Involved Side';
            app.Left = uiradiobutton(app.InvolvedSideButtonGroup);
            app.Left.Text = 'Left';
            app.Left.Value = true;
            app.Right = uiradiobutton(app.InvolvedSideButtonGroup);
            app.Right.Text = 'Right';
            app.SaveButton = uibutton(app.MMaxInfoUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save the table overwriting m_max.csv.'};
            app.SaveButton.Text = 'Save';
            app.SubjectEditFieldLabel = uilabel(app.MMaxInfoUIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.MMaxInfoUIFigure, 'text');
            app.SubjectEditField.Editable = 'off';
            app.SubjectEditField.Tooltip = {'The subject''s number will display here after selecting the folder with m_max.csv and data subfolders.'};
            % Show the figure after all components are created
            app.MMaxInfoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = m_h_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MMaxInfoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MMaxInfoUIFigure)
        end
    end
end