classdef test_table_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        SubjectFolderEditFieldLabel  matlab.ui.control.Label
        SubjectFolderEditField       matlab.ui.control.EditField
        SelectButton                 matlab.ui.control.Button
        InvolvedSideButtonGroup      matlab.ui.container.ButtonGroup
        Left                         matlab.ui.control.RadioButton
        Right                        matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            subj_dir = uigetdir(pwd, 'Select Subject Folder');
            if ischar(subj_dir)
                app.SubjectFolderEditField.Value = subj_dir;
                init_table(app, subj_dir)
            end
            % parse subject from subj_dir
            % look up involved side from database
            % check the correct involved side radio button
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            view_row = event.Indices(1);
            view_val = event.NewData;
            if view_val % turn on viewing the data
                open_view_data_fig(app, view_row)
            else % turn off viewing the data
                close_view_data_fig(app, view_row)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SubjectFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectFolderEditFieldLabel.Text = 'Subject Folder';
            app.SubjectFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.InvolvedSideButtonGroup = uibuttongroup(app.UIFigure);
            app.InvolvedSideButtonGroup.Title = 'Involved Side';
            app.Left = uiradiobutton(app.InvolvedSideButtonGroup);
            app.Left.Text = 'Left';
            app.Left.Value = true;
            app.Right = uiradiobutton(app.InvolvedSideButtonGroup);
            app.Right.Text = 'Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_table_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end